classdef ui_cycle_tracker < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DateDatePickerLabel             matlab.ui.control.Label
        DateDatePicker                  matlab.ui.control.DatePicker
        ConsoleCountTextAreaLabel       matlab.ui.control.Label
        ConsoleCountTextArea            matlab.ui.control.TextArea
        DrivelineCountLabel             matlab.ui.control.Label
        DrivelineCountTextArea          matlab.ui.control.TextArea
        ACAdapterCountTextAreaLabel     matlab.ui.control.Label
        ACAdapterCountTextArea          matlab.ui.control.TextArea
        BatteryCableCountTextAreaLabel  matlab.ui.control.Label
        BatteryCableCountTextArea       matlab.ui.control.TextArea
        ConnectDisconnectCycleTrackerLabel  matlab.ui.control.Label
        StartTimeLabel                  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        HourDropDown1                   matlab.ui.control.DropDown
        MinuteDropDown1                 matlab.ui.control.DropDown
        SecondDropDown1                 matlab.ui.control.DropDown
        AMPMDropDown1                   matlab.ui.control.DropDown
        EndTimeLabel                    matlab.ui.control.Label
        UITable2                        matlab.ui.control.Table
        AMPMDropDown2                   matlab.ui.control.DropDown
        SecondDropDown2                 matlab.ui.control.DropDown
        MinuteDropDown2                 matlab.ui.control.DropDown
        HourDropDown2                   matlab.ui.control.DropDown
        CountButton                     matlab.ui.control.Button
        EnterAPPFilePathEditFieldLabel  matlab.ui.control.Label
        EnterAPPFilePathEditField       matlab.ui.control.EditField
        UTCLabel                        matlab.ui.control.Label
        UTCLabel_2                      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % add folder with 'cycle_tracker.m' just in case
            addpath('C:\Users\DanielleVillaruel\OneDrive - BiVACOR Inc\Desktop\SANDBOX\Driveline (Dis)Connect Testing\'); 

        end

        % Button pushed function: CountButton
        function CountButtonPushed(app, event)
            
            % set default file path
            file_path = dir(fullfile("C:\Users\DanielleVillaruel\OneDrive - BiVACOR Inc\Desktop\SANDBOX\Driveline (Dis)Connect Testing\Connect-Disconnect Test\*.csv"));
        
            % use date input -- error if no date entered
            date = app.DateDatePicker.Value; % returns datetime (dd-mm-yyyy; ex, 22-Aug-2025)
            date = char(date);
            
            % fix time inputs             
            hour_start = app.HourDropDown1.Value; % returns char (OK because time inputs are char)
            minute_start = app.MinuteDropDown1.Value;
            second_start = app.SecondDropDown1.Value;
            ampm_start = app.AMPMDropDown1.Value;
            
            start_time = append(date,' ',hour_start,minute_start,second_start,' ',ampm_start); 
            %disp(start_time)
                % format: 'dd-MMM-yyyy hhmmss a'

            hour_end = app.HourDropDown2.Value;
            minute_end = app.MinuteDropDown2.Value;
            second_end = app.SecondDropDown2.Value;
            ampm_end = app.AMPMDropDown2.Value;
            
            end_time = append(date,' ',hour_end,minute_end,second_end,' ',ampm_end);
                        
            
            % check whether path changed in enterAPP...
            if ~isempty(app.EnterAPPFilePathEditField.Value)
                file_path = dir(fullfile(app.EnterAPPFilePathEditField.Value,'*.csv')); % may want to add '*.csv' so they can just enter folder
                %disp(file_path)
            end
            
            
            % utilize tracker function
            [c,d,b,a] = cycle_tracker(start_time,end_time,file_path);
            
            % set count values
            app.ConsoleCountTextArea.Value = string(c);
            app.DrivelineCountTextArea.Value = string(d);
            app.BatteryCableCountTextArea.Value = string(b);
            app.ACAdapterCountTextArea.Value = string(a);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DateDatePickerLabel = uilabel(app.UIFigure);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.UIFigure);
            app.ConsoleCountTextAreaLabel = uilabel(app.UIFigure);
            app.ConsoleCountTextAreaLabel.Text = 'Console Count';
            app.ConsoleCountTextArea = uitextarea(app.UIFigure);
            app.DrivelineCountLabel = uilabel(app.UIFigure);
            app.DrivelineCountLabel.Text = 'Driveline Count';
            app.DrivelineCountTextArea = uitextarea(app.UIFigure);
            app.ACAdapterCountTextAreaLabel = uilabel(app.UIFigure);
            app.ACAdapterCountTextAreaLabel.Text = 'AC Adapter Count';
            app.ACAdapterCountTextArea = uitextarea(app.UIFigure);
            app.BatteryCableCountTextAreaLabel = uilabel(app.UIFigure);
            app.BatteryCableCountTextAreaLabel.Text = 'Battery Cable Count';
            app.BatteryCableCountTextArea = uitextarea(app.UIFigure);
            app.ConnectDisconnectCycleTrackerLabel = uilabel(app.UIFigure);
            app.ConnectDisconnectCycleTrackerLabel.Text = 'Connect-Disconnect Cycle Tracker';
            app.StartTimeLabel = uilabel(app.UIFigure);
            app.StartTimeLabel.Text = 'Start Time';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Hour'; 'Minute'; 'Second'; 'AM/PM'; 'Timezone'};
            app.UITable.RowName = {};
            app.HourDropDown1 = uidropdown(app.UIFigure);
            app.HourDropDown1.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12'};
            app.HourDropDown1.Value = '01';
            app.MinuteDropDown1 = uidropdown(app.UIFigure);
            app.MinuteDropDown1.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60'};
            app.MinuteDropDown1.Value = '01';
            app.SecondDropDown1 = uidropdown(app.UIFigure);
            app.SecondDropDown1.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60'};
            app.SecondDropDown1.Value = '01';
            app.AMPMDropDown1 = uidropdown(app.UIFigure);
            app.AMPMDropDown1.Items = {'AM', 'PM'};
            app.AMPMDropDown1.Value = 'AM';
            app.EndTimeLabel = uilabel(app.UIFigure);
            app.EndTimeLabel.Text = 'End Time';
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Hour'; 'Minute'; 'Second'; 'AM/PM'; 'Timezone'};
            app.UITable2.RowName = {};
            app.AMPMDropDown2 = uidropdown(app.UIFigure);
            app.AMPMDropDown2.Items = {'AM', 'PM'};
            app.AMPMDropDown2.Value = 'AM';
            app.SecondDropDown2 = uidropdown(app.UIFigure);
            app.SecondDropDown2.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60'};
            app.SecondDropDown2.Value = '01';
            app.MinuteDropDown2 = uidropdown(app.UIFigure);
            app.MinuteDropDown2.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25', '26', '27', '28', '29', '30', '31', '32', '33', '34', '35', '36', '37', '38', '39', '40', '41', '42', '43', '44', '45', '46', '47', '48', '49', '50', '51', '52', '53', '54', '55', '56', '57', '58', '59', '60'};
            app.MinuteDropDown2.Value = '01';
            app.HourDropDown2 = uidropdown(app.UIFigure);
            app.HourDropDown2.Items = {'01', '02', '03', '04', '05', '06', '07', '08', '09', '10', '11', '12'};
            app.HourDropDown2.Value = '01';
            app.CountButton = uibutton(app.UIFigure, 'push');
            app.CountButton.ButtonPushedFcn = createCallbackFcn(app, @CountButtonPushed, true);
            app.CountButton.Text = 'Count';
            app.EnterAPPFilePathEditFieldLabel = uilabel(app.UIFigure);
            app.EnterAPPFilePathEditFieldLabel.Text = 'Enter APP File Path:';
            app.EnterAPPFilePathEditField = uieditfield(app.UIFigure, 'text');
            app.UTCLabel = uilabel(app.UIFigure);
            app.UTCLabel.Text = 'UTC';
            app.UTCLabel_2 = uilabel(app.UIFigure);
            app.UTCLabel_2.Text = 'UTC';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui_cycle_tracker

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end